classdef AverageHistogramAnalysis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        HelpButton                      matlab.ui.control.Button
        ClearListButton                 matlab.ui.control.Button
        FilelistListBoxLabel            matlab.ui.control.Label
        FilelistListBox                 matlab.ui.control.ListBox
        FoldersincludedListBoxLabel     matlab.ui.control.Label
        FoldersincludedListBox          matlab.ui.control.ListBox
        AddFolderButton                 matlab.ui.control.Button
        ColorchannelEditFieldLabel      matlab.ui.control.Label
        ColorchannelEditField           matlab.ui.control.EditField
        FilesselectedEditFieldLabel     matlab.ui.control.Label
        FilesselectedEditField          matlab.ui.control.NumericEditField
        AnalyzeSelectedFilesButton      matlab.ui.control.Button
        RightPanel                      matlab.ui.container.Panel
        TabGroup                        matlab.ui.container.TabGroup
        IndividualimageanalysisTab      matlab.ui.container.Tab
        UITable                         matlab.ui.control.Table
        UIAxes_histSelect               matlab.ui.control.UIAxes
        UIAxes_imSelect                 matlab.ui.control.UIAxes
        FiguresbasedonallimagesTab      matlab.ui.container.Tab
        UIAxes_histTotal                matlab.ui.control.UIAxes
        UIAxes_cdfTotal                 matlab.ui.control.UIAxes
        UIAxes_endpoints                matlab.ui.control.UIAxes
        StatsbasedonallimagesPanel      matlab.ui.container.Panel
        PercentileSpinnerLabel          matlab.ui.control.Label
        PercentileSpinner               matlab.ui.control.Spinner
        TotalpercentileintensityEditFieldLabel  matlab.ui.control.Label
        TotalpercentileintensityEditField  matlab.ui.control.NumericEditField
        TotalMaximumintensityEditFieldLabel  matlab.ui.control.Label
        TotalMaximumintensityEditField  matlab.ui.control.NumericEditField
        TotalMinimumintensityEditFieldLabel  matlab.ui.control.Label
        TotalMinimumintensityEditField  matlab.ui.control.NumericEditField
        TotalmeanintensityEditFieldLabel  matlab.ui.control.Label
        TotalmeanintensityEditField     matlab.ui.control.NumericEditField
        SavetableButton                 matlab.ui.control.Button
        UIAxes_imAVG                    matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        selected_image
        selected_image_pos
        total_image
        average_image
        
        UIAxes_prct
        
    end
    
    methods (Access = private)
        
        
        function displayEndPointsScatterplot(app)
            MeanIntensity = app.UITable.Data.('Mean Intensity');
            x = ones(1, length(MeanIntensity));
            plot(x, MeanIntensity, 'bx', 'Parent',app.UIAxes_endpoints);
            hold(app.UIAxes_endpoints, "on");
            
            x = 1;
            plot(x, app.TotalmeanintensityEditField.Value, 'b_', 'MarkerSize', 20, 'Parent',app.UIAxes_endpoints);
            
            Thresh = app.UITable.Data.('Percentile Intensity');
            x = 1.15* ones(1, length(Thresh));
            plot(x, Thresh, 'rx', 'Parent',app.UIAxes_endpoints);
            
            x = 1.15;
            plot(x, app.TotalpercentileintensityEditField.Value, 'r_', 'MarkerSize', 20, 'Parent',app.UIAxes_endpoints);
            
            grid(app.UIAxes_endpoints, "on");
            app.UIAxes_endpoints.XLim = [0.9, 1.3];
            app.UIAxes_endpoints.XTick = [1, 1.15];
            app.UIAxes_endpoints.XTickLabels = {'Mean Intensity','Percentile Intensity'};
            
            hold(app.UIAxes_endpoints, "off");
        end
        
        
        function updateSelectedImage(app)
            imshow(app.selected_image,[],'Parent',app.UIAxes_imSelect);
            histogram(app.selected_image,'BinMethod','integers','Normalization', 'probability','Parent',app.UIAxes_histSelect, 'EdgeColor', 'none', 'FaceColor', 'blue');
            %title('Parent', app.UIAxes_imSelect, app.UITable.Data(indices(1),2).('File Name'), 'Interpreter', 'none');
        end
    end
    
    methods (Access = public)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.RightPanel.Enable = 'off';
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {763, 763};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {376, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Button pushed function: AddFolderButton
        function AddFolderButtonPushed(app, event)
            try
                folder = uigetdir;
                
                if ~ismember(folder, app.FoldersincludedListBox.Items)
                    prog = uiprogressdlg(app.UIFigure,'Title','Please Wait',...
                        'Message','Loading file paths');
                    cd(folder)
                    FileList = dir(fullfile(folder, '*.TIF'));
                    app.FoldersincludedListBox.Items{end+1} = folder;
                    for ii = 1:size(FileList,1)
                        app.FilelistListBox.Items{end+1} = fullfile(FileList(ii).folder, FileList(ii).name);
                        prog.Value = ii/size(FileList,1);
                    end
                    app.FilelistListBox.Value = {};
                    app.ColorchannelEditField.Value = "";
                    app.FilesselectedEditField.Value = 0;
                    app.RightPanel.Enable = 'off';
                    
                    prog.Message = strcat('Displaying file list (', int2str(ii), ' files added)');
                    prog.Indeterminate = 'on';
                    pause(0.2);
                    close(prog);
                else
                    uialert(app.UIFigure, 'This folder is already included', 'Something Went Wrong');
                end
                
                
            catch ME
                % If problem reading image, display error message
                uialert(app.UIFigure, ME.message, 'Something Went Wrong');
                return;
            end 
        end

        % Button pushed function: ClearListButton
        function ClearListButtonPushed(app, event)
            app.FoldersincludedListBox.Value = {};
            app.FilelistListBox.Value = {};
            app.FoldersincludedListBox.Items = {};
            app.FilelistListBox.Items = {};
        end

        % Value changed function: FoldersincludedListBox
        function FoldersincludedListBoxValueChanged(app, event)
            app.FoldersincludedListBox.Value = {};
        end

        % Value changed function: FilelistListBox
        function FilelistListBoxValueChanged(app, event)
            prog = uiprogressdlg(app.UIFigure,'Title','Please Wait',...
                'Message','Searching for corresponding files');
            
            app.RightPanel.Enable = 'off';
            selected_file = app.FilelistListBox.Value{1};
            app.FilelistListBox.Value = {};
            app.selected_image = imread(selected_file);
            updateSelectedImage(app);
            
            num_img = 0;
            selected_channel = selected_file(end-5:end);
            
            for ii = 1:size(app.FilelistListBox.Items, 2)
                current_file = app.FilelistListBox.Items{ii};
                current_channel = current_file(end-5:end);
                
                if (current_channel == selected_channel)
                    current_img = imread(current_file);
                    
                    if(size(current_img) == size(app.selected_image))
                        
                        num_img = num_img + 1;
                        
                        app.FilelistListBox.Value{end+1} = current_file;
                        if isequal(current_file, selected_file)
                            app.selected_image_pos = num_img;
                        end

                    end
                end
                prog.Value = ii/size(app.FilelistListBox.Items, 2);
            end
            
            app.ColorchannelEditField.Value = selected_channel(1:2);
            app.FilesselectedEditField.Value = num_img;
            
            prog.Message = 'Updating file list';
            pause(0.2);
            close(prog);                       
        end

        % Button pushed function: AnalyzeSelectedFilesButton
        function AnalyzeSelectedFilesButtonPushed(app, event)
            
            if (isempty(app.FilelistListBox.Value))
                message = sprintf('Please choose a file first');
                uialert(app.UIFigure, message,'Help',...
                'Icon','info');
            else
            
            
            app.RightPanel.Enable = 'on';
            prog = uiprogressdlg(app.UIFigure,'Title','Please Wait',...
                'Message','Analyzing images');
            
            app.total_image = zeros([size(app.selected_image) size(app.FilelistListBox.Value, 2)]);
            
            sz = [size(app.FilelistListBox.Value, 2) 4];
            varTypes = {'int16', 'string','double','double'};
            varNames = {'#', 'File Name','Mean Intensity','Percentile Intensity'};
            table1 = table('Size',sz,'VariableTypes',varTypes,'VariableNames',varNames);
            
            for ii = 1:size(app.FilelistListBox.Value, 2)
                current_file = app.FilelistListBox.Value{ii};
                current_img = imread(current_file);
                [~,current_file_name,~] = fileparts(current_file);
                table1(ii,:) = {ii, current_file_name,mean(current_img(:)), prctile(current_img(:), app.PercentileSpinner.Value)};
                app.total_image(:,:,ii) = current_img;
                prog.Value = ii/size(app.FilelistListBox.Value, 2);
            end
            
%             prog.Message = 'Displaying selected image';
%             updateSelectedImage(app);
            
            prog.Message = 'Displaying average image';
            app.average_image = mean(app.total_image, 3);
            imshow(app.average_image,[],'Parent',app.UIAxes_imAVG);
            
            prog.Message = 'Displaying histogram';
            histogram(app.total_image(:),'BinMethod','integers','Normalization', 'probability','Parent',app.UIAxes_histTotal, 'EdgeColor', 'none', 'FaceColor', 'blue');
            
            prog.Message = 'Displaying cumulative density function';
            histogram(app.total_image(:),'BinMethod','integers','Normalization', 'cdf','Parent',app.UIAxes_cdfTotal,'EdgeColor', 'none', 'FaceColor', 'red');
            
            app.TotalMaximumintensityEditField.Value = max(app.total_image, [], 'all');
            app.TotalMinimumintensityEditField.Value = min(app.total_image, [], 'all');
            app.TotalmeanintensityEditField.Value = mean(app.total_image, 'all');
            
            
            app.UITable.Data = table1;
            removeStyle(app.UITable);
            s1 = uistyle('BackgroundColor','y', 'FontColor', 'black');
            addStyle(app.UITable,s1,'cell', [app.selected_image_pos 2]);
            app.UIAxes_imSelect.Title.BackgroundColor = 'y';
            app.UIAxes_histSelect.Title.BackgroundColor = 'y';
            
            PercentileSpinnerValueChanged(app, event)
            
            prog.Message = 'Displaying endpoints';
            displayEndPointsScatterplot(app);
            
            prog.Message = 'Almost done';
            pause(1);
            close(prog);
            
            end
        end

        % Button pushed function: HelpButton
        function HelpButtonPushed(app, event)
            message = sprintf('Please add folders containing d0, d1, d2, d3,...');
            uialert(app.UIFigure, message,'Help',...
            'Icon','info');
        end

        % Value changed function: PercentileSpinner
        function PercentileSpinnerValueChanged(app, event)
            app.TotalpercentileintensityEditField.Value = prctile(app.total_image(:), app.PercentileSpinner.Value);
            
            delete(app.UIAxes_prct);
            app.UIAxes_prct = xline(app.UIAxes_histTotal, app.TotalpercentileintensityEditField.Value, '--r');
        end

        % Value changed function: TotalpercentileintensityEditField
        function TotalpercentileintensityEditFieldValueChanged(app, event)
            delete(app.UIAxes_prct);
            app.UIAxes_prct = xline(app.UIAxes_histTotal, app.TotalpercentileintensityEditField.Value, '--r');
            
            num_less = sum(app.total_image(:) < app.TotalpercentileintensityEditField.Value);
            num_equal = sum(app.total_image(:) == app.TotalpercentileintensityEditField.Value);
            app.PercentileSpinner.Value = (num_less + 0.5*num_equal)*100 / length(app.total_image(:));
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            indices = event.Indices;
            
            if (size(indices,1) == 1 && app.selected_image_pos ~= app.UITable.Data(indices(1),1).('#'))
                prog = uiprogressdlg(app.UIFigure,'Title','Please Wait',...
                    'Message','Updating selected image');
                
                app.selected_image_pos = app.UITable.Data(indices(1),1).('#');
                app.selected_image = app.total_image(:,:,app.selected_image_pos);
                removeStyle(app.UITable);
                s1 = uistyle('BackgroundColor','y', 'FontColor', 'black');
                addStyle(app.UITable,s1,'cell',[app.selected_image_pos 2]);
                updateSelectedImage(app);
                
                prog.Value = 1;
                prog.Message = 'Almost done';
                pause(1);
                close(prog);
            end
        end

        % Button pushed function: SavetableButton
        function SavetableButtonPushed(app, event)
            prog = uiprogressdlg(app.UIFigure,'Title','Please Wait',...
                'Message','Saving table');
            try
                sz = [1 3];
                varTypes = {'double', 'double','double'};
                varNames = {'percentile','Total Mean Intensity','Total Percentile Intensity'};
                sheet2 = table('Size',sz,'VariableTypes',varTypes,'VariableNames',varNames);
                sheet2(1,:) = {app.PercentileSpinner.Value, app.TotalmeanintensityEditField.Value, app.TotalpercentileintensityEditField.Value};
                
                [file,path] =  uiputfile('table.xlsx');
                writetable(app.UITable.Data, [path,file], 'Sheet',1);
                writetable(sheet2, [path,file], 'Sheet',2);
            catch ME
                % If problem reading image, display error message
                uialert(app.UIFigure, ME.message, 'Something Went Wrong');
                return;
            end
                
            prog.Value = 1;
            prog.Message = 'Almost done';
            pause(1);
            close(prog);
                
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.HelpButton = uibutton(app.LeftPanel, 'push');
            app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.HelpButton.Text = 'Help';
            app.ClearListButton = uibutton(app.LeftPanel, 'push');
            app.ClearListButton.ButtonPushedFcn = createCallbackFcn(app, @ClearListButtonPushed, true);
            app.ClearListButton.Text = 'Clear List';
            app.FilelistListBoxLabel = uilabel(app.LeftPanel);
            app.FilelistListBoxLabel.Text = 'File list:';
            app.FilelistListBox = uilistbox(app.LeftPanel);
            app.FilelistListBox.Items = {};
            app.FilelistListBox.Multiselect = 'on';
            app.FilelistListBox.ValueChangedFcn = createCallbackFcn(app, @FilelistListBoxValueChanged, true);
            app.FilelistListBox.Value = {};
            app.FoldersincludedListBoxLabel = uilabel(app.LeftPanel);
            app.FoldersincludedListBoxLabel.Text = 'Folders included:';
            app.FoldersincludedListBox = uilistbox(app.LeftPanel);
            app.FoldersincludedListBox.Items = {};
            app.FoldersincludedListBox.ValueChangedFcn = createCallbackFcn(app, @FoldersincludedListBoxValueChanged, true);
            app.FoldersincludedListBox.Value = {};
            app.AddFolderButton = uibutton(app.LeftPanel, 'push');
            app.AddFolderButton.ButtonPushedFcn = createCallbackFcn(app, @AddFolderButtonPushed, true);
            app.AddFolderButton.Text = 'Add Folder';
            app.ColorchannelEditFieldLabel = uilabel(app.LeftPanel);
            app.ColorchannelEditFieldLabel.Text = 'Color channel';
            app.ColorchannelEditField = uieditfield(app.LeftPanel, 'text');
            app.ColorchannelEditField.Editable = 'off';
            app.FilesselectedEditFieldLabel = uilabel(app.LeftPanel);
            app.FilesselectedEditFieldLabel.Text = 'Files selected';
            app.FilesselectedEditField = uieditfield(app.LeftPanel, 'numeric');
            app.FilesselectedEditField.Limits = [0 Inf];
            app.FilesselectedEditField.Editable = 'off';
            app.AnalyzeSelectedFilesButton = uibutton(app.LeftPanel, 'push');
            app.AnalyzeSelectedFilesButton.ButtonPushedFcn = createCallbackFcn(app, @AnalyzeSelectedFilesButtonPushed, true);
            app.AnalyzeSelectedFilesButton.Text = 'Analyze Selected Files';
            app.RightPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.RightPanel);
            app.IndividualimageanalysisTab = uitab(app.TabGroup);
            app.IndividualimageanalysisTab.Title = 'Individual image analysis';
            app.UITable = uitable(app.IndividualimageanalysisTab);
            app.UITable.ColumnName = {'#'; 'File Name'; 'Mean Intensity'; 'Percentile Intensity'};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = [true false true true];
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.UIAxes_histSelect = uiaxes(app.IndividualimageanalysisTab);
            title(app.UIAxes_histSelect, 'Selected Image Histogram')
            zlabel(app.UIAxes_histSelect, 'Z')
            app.UIAxes_imSelect = uiaxes(app.IndividualimageanalysisTab);
            title(app.UIAxes_imSelect, 'Selected Image')
            app.UIAxes_imSelect.XTick = [];
            app.UIAxes_imSelect.YTick = [];
            app.FiguresbasedonallimagesTab = uitab(app.TabGroup);
            app.FiguresbasedonallimagesTab.Title = 'Figures based on all images';
            app.UIAxes_histTotal = uiaxes(app.FiguresbasedonallimagesTab);
            title(app.UIAxes_histTotal, 'Total Histogram')
            zlabel(app.UIAxes_histTotal, 'Z')
            app.UIAxes_cdfTotal = uiaxes(app.FiguresbasedonallimagesTab);
            title(app.UIAxes_cdfTotal, 'Total Cumulative Density Function')
            zlabel(app.UIAxes_cdfTotal, 'Z')
            app.UIAxes_endpoints = uiaxes(app.FiguresbasedonallimagesTab);
            title(app.UIAxes_endpoints, 'Scatterplot of Background Endpoints')
            ylabel(app.UIAxes_endpoints, 'Intensity')
            app.StatsbasedonallimagesPanel = uipanel(app.RightPanel);
            app.StatsbasedonallimagesPanel.Title = 'Stats based on all images';
            app.PercentileSpinnerLabel = uilabel(app.StatsbasedonallimagesPanel);
            app.PercentileSpinnerLabel.Text = 'Percentile';
            app.PercentileSpinner = uispinner(app.StatsbasedonallimagesPanel);
            app.PercentileSpinner.Step = 0.5;
            app.PercentileSpinner.Limits = [0 100];
            app.PercentileSpinner.ValueChangedFcn = createCallbackFcn(app, @PercentileSpinnerValueChanged, true);
            app.PercentileSpinner.Value = 99.5;
            app.TotalpercentileintensityEditFieldLabel = uilabel(app.StatsbasedonallimagesPanel);
            app.TotalpercentileintensityEditFieldLabel.Text = 'Total percentile intensity';
            app.TotalpercentileintensityEditField = uieditfield(app.StatsbasedonallimagesPanel, 'numeric');
            app.TotalpercentileintensityEditField.ValueChangedFcn = createCallbackFcn(app, @TotalpercentileintensityEditFieldValueChanged, true);
            app.TotalMaximumintensityEditFieldLabel = uilabel(app.StatsbasedonallimagesPanel);
            app.TotalMaximumintensityEditFieldLabel.Text = 'Total Maximum intensity';
            app.TotalMaximumintensityEditField = uieditfield(app.StatsbasedonallimagesPanel, 'numeric');
            app.TotalMaximumintensityEditField.Editable = 'off';
            app.TotalMinimumintensityEditFieldLabel = uilabel(app.StatsbasedonallimagesPanel);
            app.TotalMinimumintensityEditFieldLabel.Text = 'Total Minimum intensity';
            app.TotalMinimumintensityEditField = uieditfield(app.StatsbasedonallimagesPanel, 'numeric');
            app.TotalMinimumintensityEditField.Editable = 'off';
            app.TotalmeanintensityEditFieldLabel = uilabel(app.StatsbasedonallimagesPanel);
            app.TotalmeanintensityEditFieldLabel.Text = 'Total mean intensity';
            app.TotalmeanintensityEditField = uieditfield(app.StatsbasedonallimagesPanel, 'numeric');
            app.TotalmeanintensityEditField.Editable = 'off';
            app.SavetableButton = uibutton(app.StatsbasedonallimagesPanel, 'push');
            app.SavetableButton.ButtonPushedFcn = createCallbackFcn(app, @SavetableButtonPushed, true);
            app.SavetableButton.Text = 'Save table';
            app.UIAxes_imAVG = uiaxes(app.StatsbasedonallimagesPanel);
            title(app.UIAxes_imAVG, 'Average Image')
            app.UIAxes_imAVG.XTick = [];
            app.UIAxes_imAVG.YTick = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AverageHistogramAnalysis

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end