classdef AverageHistogramAnalysisFolderComparison < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        Folder1Panel                   matlab.ui.container.Panel
        Folder1BrowseButton            matlab.ui.control.Button
        Folder1BrowseEditField         matlab.ui.control.EditField
        channelLabel                   matlab.ui.control.Label
        Folder1ChannelDropDown         matlab.ui.control.DropDown
        Folder1ChannelCountLabel       matlab.ui.control.Label
        Folder1TotalCountLabel         matlab.ui.control.Label
        Folder1includehistogramCheckBox  matlab.ui.control.CheckBox
        histogramcolorDropDownLabel    matlab.ui.control.Label
        Folder1histogramcolorDropDown  matlab.ui.control.DropDown
        Folder2Panel                   matlab.ui.container.Panel
        Folder2BrowseButton            matlab.ui.control.Button
        Folder2BrowseEditField         matlab.ui.control.EditField
        channelLabel_2                 matlab.ui.control.Label
        Folder2ChannelDropDown         matlab.ui.control.DropDown
        Folder2ChannelCountLabel       matlab.ui.control.Label
        Folder2TotalCountLabel         matlab.ui.control.Label
        Folder2includehistogramCheckBox  matlab.ui.control.CheckBox
        histogramcolorDropDownLabel_2  matlab.ui.control.Label
        Folder2histogramcolorDropDown  matlab.ui.control.DropDown
        Folder3Panel                   matlab.ui.container.Panel
        Folder3BrowseButton            matlab.ui.control.Button
        Folder3BrowseEditField         matlab.ui.control.EditField
        channelLabel_3                 matlab.ui.control.Label
        Folder3ChannelDropDown         matlab.ui.control.DropDown
        Folder3ChannelCountLabel       matlab.ui.control.Label
        Folder3TotalCountLabel         matlab.ui.control.Label
        Folder3includehistogramCheckBox  matlab.ui.control.CheckBox
        histogramcolorDropDownLabel_3  matlab.ui.control.Label
        Folder3histogramcolorDropDown  matlab.ui.control.DropDown
        CompareHistogramsButton        matlab.ui.control.Button
        SaveTableButton                matlab.ui.control.Button
        RightPanel                     matlab.ui.container.Panel
        UITable                        matlab.ui.control.Table
        UIAxes                         matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        folder1_channel_list % list of channels in folder 1 (num array)
        folder2_channel_list
        folder3_channel_list
        folder1_file_list % file list of folder 1 (struct)
        folder2_file_list
        folder3_file_list
    end
    
    methods (Access = private)
        
        function results = updateTableColor(app)
            colors = {app.Folder1histogramcolorDropDown.Value app.Folder2histogramcolorDropDown.Value app.Folder3histogramcolorDropDown.Value};
            for ii = 1:3
                s1 = uistyle("BackgroundColor", char(colors(ii)));
                addStyle(app.UITable,s1,'row', ii);
%                 if isequal(char(app.UITable.Data(ii, 2).('display color')), 'blue') || ...
%                         isequal(char(app.UITable.Data(ii, 2).('display color')), 'black')
%                     s1 = uistyle("FontColor", 'white');
%                     addStyle(app.UITable,s1,'row', ii);
%                 else
%                     s1 = uistyle("FontColor", 'black');
%                     addStyle(app.UITable,s1,'row', ii);
%                 end
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            sz = [3 5];
            varTypes = {'int16', 'string','double','double', 'double'};
            varNames = {'folder #', 'folder directory','hist mean','hist median', 'hist mode'};
            app.UITable.Data = table('Size',sz,'VariableTypes',varTypes,'VariableNames',varNames);
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {570, 570};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {277, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Button pushed function: Folder2BrowseButton
        function Folder2BrowseButtonPushed(app, event)
            selpath = uigetdir;
            cd(selpath)
            app.Folder2BrowseEditField.Value = selpath;
            FileList = dir('*.TIF');
            [~,idx] = sort([FileList.datenum]);
            app.folder2_file_list = FileList(idx);
            
            file_names = {app.folder2_file_list.name};

            app.folder2_channel_list = 0;
            for ii = 1:size(file_names,2)
                app.folder2_channel_list(ii) = str2num(file_names{ii}(end-4));
            end
            
            app.Folder2ChannelDropDown.Items = num2cell(num2str(unique(app.folder2_channel_list)'));
            
            app.Folder2BrowseEditField.Enable = 'on';
            app.Folder2ChannelDropDown.Enable = 'on';
            
            num = size(file_names,2);
            app.Folder2TotalCountLabel.Text = sprintf("# TIF files available: %.0f", num);
            app.Folder2TotalCountLabel.Enable = 'on';
            
            Folder2ChannelDropDownValueChanged(app, event);
            
            app.Folder2histogramcolorDropDown.Enable = 'on';
            app.Folder2includehistogramCheckBox.Value = 1;
            app.Folder2includehistogramCheckBox.Enable = 'on';
        end

        % Button pushed function: Folder3BrowseButton
        function Folder3BrowseButtonPushed(app, event)
            selpath = uigetdir;
            cd(selpath)
            app.Folder3BrowseEditField.Value = selpath;
            FileList = dir('*.TIF');
            [~,idx] = sort([FileList.datenum]);
            app.folder3_file_list = FileList(idx);
            
            file_names = {app.folder3_file_list.name};

            app.folder3_channel_list = 0;
            for ii = 1:size(file_names,2)
                app.folder3_channel_list(ii) = str2num(file_names{ii}(end-4));
            end
            
            app.Folder3ChannelDropDown.Items = num2cell(num2str(unique(app.folder3_channel_list)'));
            
            app.Folder3BrowseEditField.Enable = 'on';
            app.Folder3ChannelDropDown.Enable = 'on';
            
            num = size(file_names,2);
            app.Folder3TotalCountLabel.Text = sprintf("# TIF files available: %.0f", num);
            app.Folder3TotalCountLabel.Enable = 'on';
            
            Folder3ChannelDropDownValueChanged(app, event);
            
            app.Folder3histogramcolorDropDown.Enable = 'on';
            app.Folder3includehistogramCheckBox.Value = 1;
            app.Folder3includehistogramCheckBox.Enable = 'on';
        end

        % Button pushed function: Folder1BrowseButton
        function Folder1BrowseButtonPushed(app, event)
            selpath = uigetdir;
            cd(selpath)
            app.Folder1BrowseEditField.Value = selpath;
            FileList = dir('*.TIF');
            [~,idx] = sort([FileList.datenum]);
            app.folder1_file_list = FileList(idx);
            
            file_names = {app.folder1_file_list.name};

            app.folder1_channel_list = 0;
            for ii = 1:size(file_names,2)
                app.folder1_channel_list(ii) = str2num(file_names{ii}(end-4));
            end
            
            app.Folder1ChannelDropDown.Items = num2cell(num2str(unique(app.folder1_channel_list)'));
            
            app.Folder1BrowseEditField.Enable = 'on';
            app.Folder1ChannelDropDown.Enable = 'on';
            
            num = size(file_names,2);
            app.Folder1TotalCountLabel.Text = sprintf("# TIF files available: %.0f", num);
            app.Folder1TotalCountLabel.Enable = 'on';
            
            Folder1ChannelDropDownValueChanged(app, event);
            
            app.Folder1histogramcolorDropDown.Enable = 'on';
            app.Folder1includehistogramCheckBox.Value = 1;
            app.Folder1includehistogramCheckBox.Enable = 'on';
        end

        % Value changed function: Folder2ChannelDropDown
        function Folder2ChannelDropDownValueChanged(app, event)
            value = app.Folder2ChannelDropDown.Value;
            
            num = sum(app.folder2_channel_list == str2num(value));
            app.Folder2ChannelCountLabel.Text = sprintf("# d%.0f images selected: %.0f", str2num(value), num);
            app.Folder2ChannelCountLabel.Enable = 'on';
        end

        % Value changed function: Folder3ChannelDropDown
        function Folder3ChannelDropDownValueChanged(app, event)
            value = app.Folder3ChannelDropDown.Value;
            
            num = sum(app.folder3_channel_list == str2num(value));
            app.Folder3ChannelCountLabel.Text = sprintf("# d%.0f images selected: %.0f", str2num(value), num);
            app.Folder3ChannelCountLabel.Enable = 'on';
        end

        % Value changed function: Folder1ChannelDropDown
        function Folder1ChannelDropDownValueChanged(app, event)
            value = app.Folder1ChannelDropDown.Value;
            
            num = sum(app.folder1_channel_list == str2num(value));
            app.Folder1ChannelCountLabel.Text = sprintf("# d%.0f images selected: %.0f", str2num(value), num);
            app.Folder1ChannelCountLabel.Enable = 'on';
        end

        % Button pushed function: CompareHistogramsButton
        function CompareHistogramsButtonPushed(app, event)
            prog = uiprogressdlg(app.UIFigure,'Title','Please Wait',...
                'Message','Analyzing images');
            prog.Indeterminate = 'on';
            
            hold(app.UIAxes, "on");
            
            prog.Message = 'Analyzing folder 1';
            if (app.Folder1includehistogramCheckBox.Value == 1)
                folder1_chanel_file_list = app.folder1_file_list(app.folder1_channel_list == str2num(app.Folder1ChannelDropDown.Value));
                
                img1 = imread(fullfile(folder1_chanel_file_list(1).folder, folder1_chanel_file_list(1).name));    
                folder1_img_stack = zeros([size(img1) size(folder1_chanel_file_list, 1)]);
                
                for ii = 1:size(folder1_chanel_file_list, 1)
                    folder1_img_stack(:,:,ii) = imread(fullfile(folder1_chanel_file_list(ii).folder, folder1_chanel_file_list(ii).name));    
                end
                
                histogram(folder1_img_stack(:),'BinMethod','integers', 'Normalization', 'probability', 'FaceAlpha', .7, 'EdgeColor', 'none', 'FaceColor', app.Folder1histogramcolorDropDown.Value,'Parent',app.UIAxes);
                app.UITable.Data(1,:) = {1, folder1_chanel_file_list(1).folder, mean(folder1_img_stack(:)), median(folder1_img_stack(:)), mode(folder1_img_stack(:))};
            end
            
            prog.Message = 'Analyzing folder 2';
            if (app.Folder2includehistogramCheckBox.Value == 1)
                folder2_chanel_file_list = app.folder2_file_list(app.folder2_channel_list == str2num(app.Folder2ChannelDropDown.Value));
                
                img1 = imread(fullfile(folder2_chanel_file_list(1).folder, folder2_chanel_file_list(1).name));    
                folder2_img_stack = zeros([size(img1) size(folder2_chanel_file_list, 1)]);
                
                for ii = 1:size(folder2_chanel_file_list, 1)
                    folder2_img_stack(:,:,ii) = imread(fullfile(folder2_chanel_file_list(ii).folder, folder2_chanel_file_list(ii).name));    
                end
                
                histogram(folder2_img_stack(:),'BinMethod','integers', 'Normalization', 'probability', 'FaceAlpha', .7, 'EdgeColor', 'none', 'FaceColor', app.Folder2histogramcolorDropDown.Value,'Parent',app.UIAxes);
                app.UITable.Data(2,:) = {2, folder2_chanel_file_list(1).folder, mean(folder2_img_stack(:)), median(folder2_img_stack(:)), mode(folder2_img_stack(:))};
            end
            
            prog.Message = 'Analyzing folder 3';
            if (app.Folder3includehistogramCheckBox.Value == 1)
                folder3_chanel_file_list = app.folder3_file_list(app.folder3_channel_list == str2num(app.Folder3ChannelDropDown.Value));
                
                img1 = imread(fullfile(folder3_chanel_file_list(1).folder, folder3_chanel_file_list(1).name));    
                folder3_img_stack = zeros([size(img1) size(folder3_chanel_file_list, 1)]);
                
                for ii = 1:size(folder3_chanel_file_list, 1)
                    folder3_img_stack(:,:,ii) = imread(fullfile(folder3_chanel_file_list(ii).folder, folder3_chanel_file_list(ii).name));    
                end
                
                histogram(folder3_img_stack(:),'BinMethod','integers', 'Normalization', 'probability', 'FaceAlpha', .7, 'EdgeColor', 'none', 'FaceColor', app.Folder3histogramcolorDropDown.Value,'Parent',app.UIAxes);
                app.UITable.Data(3,:) = {3, folder3_chanel_file_list(1).folder, mean(folder3_img_stack(:)), median(folder3_img_stack(:)), mode(folder3_img_stack(:))};
            end

            hold(app.UIAxes, "off");
            updateTableColor(app);
            
            prog.Message = 'Almost done';
            pause(0.5);
            close(prog);
        end

        % Button pushed function: SaveTableButton
        function SaveTableButtonPushed(app, event)
            prog = uiprogressdlg(app.UIFigure,'Title','Please Wait',...
                'Message','Saving table');
            try
                
                [file,path] =  uiputfile('table.xlsx');
                writetable(app.UITable.Data, [path,file], 'Sheet',1);
                
            catch ME
                % If problem reading image, display error message
                uialert(app.UIFigure, ME.message, 'Something Went Wrong');
                return;
            end
                
            prog.Value = 1;
            prog.Message = 'Almost done';
            pause(1);
            close(prog);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Folder1Panel = uipanel(app.LeftPanel);
            app.Folder1Panel.Title = 'Folder 1';
            app.Folder1BrowseButton = uibutton(app.Folder1Panel, 'push');
            app.Folder1BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @Folder1BrowseButtonPushed, true);
            app.Folder1BrowseButton.Text = 'Browse';
            app.Folder1BrowseEditField = uieditfield(app.Folder1Panel, 'text');
            app.Folder1BrowseEditField.Editable = 'off';
            app.Folder1BrowseEditField.Enable = 'off';
            app.channelLabel = uilabel(app.Folder1Panel);
            app.channelLabel.Text = 'channel';
            app.Folder1ChannelDropDown = uidropdown(app.Folder1Panel);
            app.Folder1ChannelDropDown.Items = {};
            app.Folder1ChannelDropDown.ValueChangedFcn = createCallbackFcn(app, @Folder1ChannelDropDownValueChanged, true);
            app.Folder1ChannelDropDown.Enable = 'off';
            app.Folder1ChannelDropDown.Value = {};
            app.Folder1ChannelCountLabel = uilabel(app.Folder1Panel);
            app.Folder1ChannelCountLabel.Enable = 'off';
            app.Folder1ChannelCountLabel.Text = '# channel images:';
            app.Folder1TotalCountLabel = uilabel(app.Folder1Panel);
            app.Folder1TotalCountLabel.Enable = 'off';
            app.Folder1TotalCountLabel.Text = '# TIF files available:';
            app.Folder1includehistogramCheckBox = uicheckbox(app.Folder1Panel);
            app.Folder1includehistogramCheckBox.Enable = 'off';
            app.Folder1includehistogramCheckBox.Text = 'include histogram';
            app.histogramcolorDropDownLabel = uilabel(app.Folder1Panel);
            app.histogramcolorDropDownLabel.Text = 'histogram color';
            app.Folder1histogramcolorDropDown = uidropdown(app.Folder1Panel);
            app.Folder1histogramcolorDropDown.Items = {'red', 'green', 'blue', 'yellow', 'magenta', 'cyan'};
            app.Folder1histogramcolorDropDown.Enable = 'off';
            app.Folder1histogramcolorDropDown.Value = 'red';
            app.Folder2Panel = uipanel(app.LeftPanel);
            app.Folder2Panel.Title = 'Folder 2';
            app.Folder2BrowseButton = uibutton(app.Folder2Panel, 'push');
            app.Folder2BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @Folder2BrowseButtonPushed, true);
            app.Folder2BrowseButton.Text = 'Browse';
            app.Folder2BrowseEditField = uieditfield(app.Folder2Panel, 'text');
            app.Folder2BrowseEditField.Editable = 'off';
            app.Folder2BrowseEditField.Enable = 'off';
            app.channelLabel_2 = uilabel(app.Folder2Panel);
            app.channelLabel_2.Text = 'channel';
            app.Folder2ChannelDropDown = uidropdown(app.Folder2Panel);
            app.Folder2ChannelDropDown.Items = {};
            app.Folder2ChannelDropDown.ValueChangedFcn = createCallbackFcn(app, @Folder2ChannelDropDownValueChanged, true);
            app.Folder2ChannelDropDown.Enable = 'off';
            app.Folder2ChannelDropDown.Value = {};
            app.Folder2ChannelCountLabel = uilabel(app.Folder2Panel);
            app.Folder2ChannelCountLabel.Enable = 'off';
            app.Folder2ChannelCountLabel.Text = '# channel images:';
            app.Folder2TotalCountLabel = uilabel(app.Folder2Panel);
            app.Folder2TotalCountLabel.Enable = 'off';
            app.Folder2TotalCountLabel.Text = '# TIF files available:';
            app.Folder2includehistogramCheckBox = uicheckbox(app.Folder2Panel);
            app.Folder2includehistogramCheckBox.Enable = 'off';
            app.Folder2includehistogramCheckBox.Text = 'include histogram';
            app.histogramcolorDropDownLabel_2 = uilabel(app.Folder2Panel);
            app.histogramcolorDropDownLabel_2.Text = 'histogram color';
            app.Folder2histogramcolorDropDown = uidropdown(app.Folder2Panel);
            app.Folder2histogramcolorDropDown.Items = {'red', 'green', 'blue', 'yellow', 'magenta', 'cyan'};
            app.Folder2histogramcolorDropDown.Enable = 'off';
            app.Folder2histogramcolorDropDown.Value = 'green';
            app.Folder3Panel = uipanel(app.LeftPanel);
            app.Folder3Panel.Title = 'Folder 3';
            app.Folder3BrowseButton = uibutton(app.Folder3Panel, 'push');
            app.Folder3BrowseButton.ButtonPushedFcn = createCallbackFcn(app, @Folder3BrowseButtonPushed, true);
            app.Folder3BrowseButton.Text = 'Browse';
            app.Folder3BrowseEditField = uieditfield(app.Folder3Panel, 'text');
            app.Folder3BrowseEditField.Editable = 'off';
            app.Folder3BrowseEditField.Enable = 'off';
            app.channelLabel_3 = uilabel(app.Folder3Panel);
            app.channelLabel_3.Text = 'channel';
            app.Folder3ChannelDropDown = uidropdown(app.Folder3Panel);
            app.Folder3ChannelDropDown.Items = {};
            app.Folder3ChannelDropDown.ValueChangedFcn = createCallbackFcn(app, @Folder3ChannelDropDownValueChanged, true);
            app.Folder3ChannelDropDown.Enable = 'off';
            app.Folder3ChannelDropDown.Value = {};
            app.Folder3ChannelCountLabel = uilabel(app.Folder3Panel);
            app.Folder3ChannelCountLabel.Enable = 'off';
            app.Folder3ChannelCountLabel.Text = '# channel images:';
            app.Folder3TotalCountLabel = uilabel(app.Folder3Panel);
            app.Folder3TotalCountLabel.Enable = 'off';
            app.Folder3TotalCountLabel.Text = '# TIF files available:';
            app.Folder3includehistogramCheckBox = uicheckbox(app.Folder3Panel);
            app.Folder3includehistogramCheckBox.Enable = 'off';
            app.Folder3includehistogramCheckBox.Text = 'include histogram';
            app.histogramcolorDropDownLabel_3 = uilabel(app.Folder3Panel);
            app.histogramcolorDropDownLabel_3.Text = 'histogram color';
            app.Folder3histogramcolorDropDown = uidropdown(app.Folder3Panel);
            app.Folder3histogramcolorDropDown.Items = {'red', 'green', 'blue', 'yellow', 'magenta', 'cyan'};
            app.Folder3histogramcolorDropDown.Enable = 'off';
            app.Folder3histogramcolorDropDown.Value = 'blue';
            app.CompareHistogramsButton = uibutton(app.LeftPanel, 'push');
            app.CompareHistogramsButton.ButtonPushedFcn = createCallbackFcn(app, @CompareHistogramsButtonPushed, true);
            app.CompareHistogramsButton.Text = 'Compare Histograms';
            app.SaveTableButton = uibutton(app.LeftPanel, 'push');
            app.SaveTableButton.ButtonPushedFcn = createCallbackFcn(app, @SaveTableButtonPushed, true);
            app.SaveTableButton.Text = 'Save Table';
            app.RightPanel = uipanel(app.GridLayout);
            app.UITable = uitable(app.RightPanel);
            app.UITable.ColumnName = {'folder #'; 'folder directory'; 'hist mean'; 'hist median'; 'hist mode'};
            app.UITable.RowName = {};
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Histograms')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AverageHistogramAnalysisFolderComparison

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end