classdef AverageHistogramAnalysis_IndivTotalAnalysis < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        CurrentFolderEditField        matlab.ui.control.EditField
        ChooseFolderButton            matlab.ui.control.Button
        FileListListBoxLabel          matlab.ui.control.Label
        FileListListBox               matlab.ui.control.ListBox
        HelpButton                    matlab.ui.control.Button
        RightPanel                    matlab.ui.container.Panel
        TabGroup                      matlab.ui.container.TabGroup
        IndividualimageanalysisTab    matlab.ui.container.Tab
        UITable                       matlab.ui.control.Table
        UIAxes_histSelect             matlab.ui.control.UIAxes
        UIAxes_imSelect               matlab.ui.control.UIAxes
        FiguresbasedonallimagesTab    matlab.ui.container.Tab
        UIAxes_histTotal              matlab.ui.control.UIAxes
        UIAxes_cdfTotal               matlab.ui.control.UIAxes
        UIAxes_endpoints              matlab.ui.control.UIAxes
        BelowstatsarebasedonallimageswithincolorchannelPanel  matlab.ui.container.Panel
        PercentileSpinnerLabel        matlab.ui.control.Label
        PercentileSpinner             matlab.ui.control.Spinner
        PercentileintensityEditFieldLabel  matlab.ui.control.Label
        PercentileintensityEditField  matlab.ui.control.NumericEditField
        ColorChannelEditFieldLabel    matlab.ui.control.Label
        ColorChannelEditField         matlab.ui.control.EditField
        TotalMaximumEditFieldLabel    matlab.ui.control.Label
        TotalMaximumEditField         matlab.ui.control.NumericEditField
        TotalMinimumEditFieldLabel    matlab.ui.control.Label
        TotalMinimumEditField         matlab.ui.control.NumericEditField
        TotalMeanEditFieldLabel       matlab.ui.control.Label
        TotalMeanEditField            matlab.ui.control.NumericEditField
        UIAxes_imAVG                  matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        total_image
        average_image
        
        UIAxes_prct
        
        Table1
        
    end
    
    methods (Access = private)
        
        
        function displayEndPointsScatterplot(app)
            MeanIntensity = app.Table1.('Mean Intensity');
            x = ones(1, length(MeanIntensity));
            plot(x, MeanIntensity, 'bx', 'Parent',app.UIAxes_endpoints);
            hold(app.UIAxes_endpoints, "on");
            
            x = 1;
            plot(x, app.TotalMeanEditField.Value, 'b_', 'MarkerSize', 20, 'Parent',app.UIAxes_endpoints);
            
            Thresh = app.Table1.('Percentile Intensity');
            x = 1.15* ones(1, length(Thresh));
            plot(x, Thresh, 'rx', 'Parent',app.UIAxes_endpoints);
            
            x = 1.15;
            plot(x, app.PercentileintensityEditField.Value, 'r_', 'MarkerSize', 20, 'Parent',app.UIAxes_endpoints);
            
            grid(app.UIAxes_endpoints, "on");
            app.UIAxes_endpoints.XLim = [0.9, 1.3];
            app.UIAxes_endpoints.XTick = [1, 1.15];
            app.UIAxes_endpoints.XTickLabels = {'Mean Intensity','Percentile Intensity'};


            hold(app.UIAxes_endpoints, "off");
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            FileList = dir('*.TIF');
            app.FileListListBox.Items = {FileList.name};
            app.CurrentFolderEditField.Value = cd;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {763, 763};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {376, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Button pushed function: ChooseFolderButton
        function ChooseFolderButtonPushed(app, event)
            try
                selpath = uigetdir;
                cd(selpath)
                app.CurrentFolderEditField.Value = selpath;
                FileList = dir('*.TIF');
                app.FileListListBox.Items = {FileList.name};
                app.FileListListBox.Value = {};
            catch ME
                % If problem reading image, display error message
                uialert(app.UIFigure, ME.message, 'Something Went Wrong');
                return;
            end 
        end

        % Value changed function: FileListListBox
        function FileListListBoxValueChanged(app, event)
            selected_file = app.FileListListBox.Value{1};
            selected_img = imread(selected_file);
            imshow(selected_img,[],'Parent',app.UIAxes_imSelect);
            histogram(selected_img,'BinMethod','integers','Normalization', 'probability','Parent',app.UIAxes_histSelect, 'EdgeColor', 'none', 'FaceColor', 'blue');
            
            selected_channel = selected_file(end-5:end);
            app.ColorChannelEditField.Value = selected_channel(1:2);
            prog = uiprogressdlg(app.UIFigure,'Title','Please Wait',...
                'Message','Analyzing images');
            
            %app.total_image = [];%zeros(size(imread(selected_file)));
            num_img = 0;
            
            sz = [0 3];
            varTypes = {'string','double','double'};
            varNames = {'File Name','Mean Intensity','Percentile Intensity'};
            app.Table1 = table('Size',sz,'VariableTypes',varTypes,'VariableNames',varNames);
            
            %figure;
            
            for ii = 1:size(app.FileListListBox.Items, 2)
                current_file = app.FileListListBox.Items{ii};
                current_channel = current_file(end-5:end);
                
                if (current_channel == selected_channel)
                    current_img = imread(current_file);
                    
                    if(size(current_img) == size(selected_img))
                        
                        num_img = num_img + 1;
                        app.Table1(end+1,:) = {current_file,mean(current_img(:)), prctile(current_img(:), app.PercentileSpinner.Value)};
                        app.total_image(:,:,num_img) = double(current_img);
                        
                        if ~ismember(current_file, app.FileListListBox.Value)
                            app.FileListListBox.Value{end+1} = current_file;
                        else
                            removeStyle(app.UITable);
                            s1 = uistyle('BackgroundColor','y', 'FontColor', 'b', 'FontWeight', 'bold');
                            addStyle(app.UITable,s1,'row',[num_img]);
                            app.UIAxes_imSelect.Title.BackgroundColor = 'y';
                            app.UIAxes_imSelect.Title.Color = 'b';
                        end
                        
                        %imshow(app.total_image,[]);
                    end
                end
                prog.Value = ii/size(app.FileListListBox.Items, 2);
            end
            
            prog.Message = 'Displaying average image';
            app.average_image = mean(app.total_image, 3);
            imshow(app.average_image,[],'Parent',app.UIAxes_imAVG);
            
            prog.Message = 'Displaying histogram';
            histogram(app.total_image(:),'BinMethod','integers','Normalization', 'probability','Parent',app.UIAxes_histTotal, 'EdgeColor', 'none', 'FaceColor', 'blue');
            
            prog.Message = 'Displaying cumulative density function (this may take a while)';
            [fii, xii] = ksdensity(app.total_image(:),'Function','cdf', 'NumPoints', 20);
            plot(xii,fii, 'Parent',app.UIAxes_cdfTotal);
            
            app.TotalMaximumEditField.Value = max(app.total_image, [], 'all');
            app.TotalMinimumEditField.Value = min(app.total_image, [], 'all');
            app.TotalMeanEditField.Value = mean(app.total_image, 'all');
            app.UITable.Data = app.Table1;
            
            PercentileSpinnerValueChanged(app, event)
            
            prog.Message = 'Displaying endpoints';
            displayEndPointsScatterplot(app);
            
            prog.Message = 'Almost done';
            pause(1);
            close(prog);                       
        end

        % Button pushed function: HelpButton
        function HelpButtonPushed(app, event)
            message = sprintf('Please choose folder containing d0, d1, d2, d3,...');
            uialert(app.UIFigure, message,'Help',...
            'Icon','info');
        end

        % Value changed function: PercentileSpinner
        function PercentileSpinnerValueChanged(app, event)
            app.PercentileintensityEditField.Value = prctile(app.total_image(:), app.PercentileSpinner.Value);
            
            delete(app.UIAxes_prct);
            app.UIAxes_prct = xline(app.UIAxes_histTotal, app.PercentileintensityEditField.Value, '--r');
        end

        % Value changed function: PercentileintensityEditField
        function PercentileintensityEditFieldValueChanged(app, event)
            delete(app.UIAxes_prct);
            app.UIAxes_prct = xline(app.UIAxes_histTotal, app.PercentileintensityEditField.Value, '--r');
            
            num_less = sum(app.total_image(:) < app.PercentileintensityEditField.Value);
            num_equal = sum(app.total_image(:) == app.PercentileintensityEditField.Value);
            app.PercentileSpinner.Value = (num_less + 0.5*num_equal)*100 / length(app.total_image(:));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.CurrentFolderEditField = uieditfield(app.LeftPanel, 'text');
            app.CurrentFolderEditField.Value = 'C:\';
            app.ChooseFolderButton = uibutton(app.LeftPanel, 'push');
            app.ChooseFolderButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseFolderButtonPushed, true);
            app.ChooseFolderButton.Text = 'Choose Folder';
            app.FileListListBoxLabel = uilabel(app.LeftPanel);
            app.FileListListBoxLabel.Text = 'File List:';
            app.FileListListBox = uilistbox(app.LeftPanel);
            app.FileListListBox.Multiselect = 'on';
            app.FileListListBox.ValueChangedFcn = createCallbackFcn(app, @FileListListBoxValueChanged, true);
            app.FileListListBox.Value = {};
            app.HelpButton = uibutton(app.LeftPanel, 'push');
            app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.HelpButton.Text = 'Help';
            app.RightPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.RightPanel);
            app.IndividualimageanalysisTab = uitab(app.TabGroup);
            app.IndividualimageanalysisTab.Title = 'Individual image analysis';
            app.UITable = uitable(app.IndividualimageanalysisTab);
            app.UITable.ColumnName = {'File Name'; 'Mean Intensity'; 'Percentile Intensity'};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = [false true true];
            app.UIAxes_histSelect = uiaxes(app.IndividualimageanalysisTab);
            title(app.UIAxes_histSelect, 'Selected File Histogram')
            zlabel(app.UIAxes_histSelect, 'Z')
            app.UIAxes_imSelect = uiaxes(app.IndividualimageanalysisTab);
            title(app.UIAxes_imSelect, 'Selected Image')
            app.UIAxes_imSelect.XTick = [];
            app.UIAxes_imSelect.YTick = [];
            app.FiguresbasedonallimagesTab = uitab(app.TabGroup);
            app.FiguresbasedonallimagesTab.Title = 'Figures based on all images';
            app.UIAxes_histTotal = uiaxes(app.FiguresbasedonallimagesTab);
            title(app.UIAxes_histTotal, 'Total Histogram')
            zlabel(app.UIAxes_histTotal, 'Z')
            app.UIAxes_cdfTotal = uiaxes(app.FiguresbasedonallimagesTab);
            title(app.UIAxes_cdfTotal, 'Total Cumulative Density Function')
            zlabel(app.UIAxes_cdfTotal, 'Z')
            app.UIAxes_endpoints = uiaxes(app.FiguresbasedonallimagesTab);
            title(app.UIAxes_endpoints, 'Scatterplot of Background Endpoints')
            ylabel(app.UIAxes_endpoints, 'Intensity')
            app.BelowstatsarebasedonallimageswithincolorchannelPanel = uipanel(app.RightPanel);
            app.BelowstatsarebasedonallimageswithincolorchannelPanel.Title = 'Below stats are based on all images within color channel';
            app.PercentileSpinnerLabel = uilabel(app.BelowstatsarebasedonallimageswithincolorchannelPanel);
            app.PercentileSpinnerLabel.Text = 'Percentile';
            app.PercentileSpinner = uispinner(app.BelowstatsarebasedonallimageswithincolorchannelPanel);
            app.PercentileSpinner.Step = 0.5;
            app.PercentileSpinner.Limits = [0 100];
            app.PercentileSpinner.ValueChangedFcn = createCallbackFcn(app, @PercentileSpinnerValueChanged, true);
            app.PercentileSpinner.Value = 99.5;
            app.PercentileintensityEditFieldLabel = uilabel(app.BelowstatsarebasedonallimageswithincolorchannelPanel);
            app.PercentileintensityEditFieldLabel.Text = 'Percentile intensity';
            app.PercentileintensityEditField = uieditfield(app.BelowstatsarebasedonallimageswithincolorchannelPanel, 'numeric');
            app.PercentileintensityEditField.ValueChangedFcn = createCallbackFcn(app, @PercentileintensityEditFieldValueChanged, true);
            app.ColorChannelEditFieldLabel = uilabel(app.BelowstatsarebasedonallimageswithincolorchannelPanel);
            app.ColorChannelEditFieldLabel.Text = 'Color Channel';
            app.ColorChannelEditField = uieditfield(app.BelowstatsarebasedonallimageswithincolorchannelPanel, 'text');
            app.ColorChannelEditField.Editable = 'off';
            app.TotalMaximumEditFieldLabel = uilabel(app.BelowstatsarebasedonallimageswithincolorchannelPanel);
            app.TotalMaximumEditFieldLabel.Text = 'Total Maximum';
            app.TotalMaximumEditField = uieditfield(app.BelowstatsarebasedonallimageswithincolorchannelPanel, 'numeric');
            app.TotalMaximumEditField.Editable = 'off';
            app.TotalMinimumEditFieldLabel = uilabel(app.BelowstatsarebasedonallimageswithincolorchannelPanel);
            app.TotalMinimumEditFieldLabel.Text = 'Total Minimum';
            app.TotalMinimumEditField = uieditfield(app.BelowstatsarebasedonallimageswithincolorchannelPanel, 'numeric');
            app.TotalMinimumEditField.Editable = 'off';
            app.TotalMeanEditFieldLabel = uilabel(app.BelowstatsarebasedonallimageswithincolorchannelPanel);
            app.TotalMeanEditFieldLabel.Text = 'Total Mean';
            app.TotalMeanEditField = uieditfield(app.BelowstatsarebasedonallimageswithincolorchannelPanel, 'numeric');
            app.TotalMeanEditField.Editable = 'off';
            app.UIAxes_imAVG = uiaxes(app.BelowstatsarebasedonallimageswithincolorchannelPanel);
            title(app.UIAxes_imAVG, 'Average Image')
            app.UIAxes_imAVG.XTick = [];
            app.UIAxes_imAVG.YTick = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AverageHistogramAnalysis_IndivTotalAnalysis

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end