classdef AverageHistogramAnalysis_Multi < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        LeftPanel                   matlab.ui.container.Panel
        CurrentFolderEditField      matlab.ui.control.EditField
        ChooseFolderButton          matlab.ui.control.Button
        FileListListBoxLabel        matlab.ui.control.Label
        FileListListBox             matlab.ui.control.ListBox
        HelpButton                  matlab.ui.control.Button
        RightPanel                  matlab.ui.container.Panel
        TabGroup                    matlab.ui.container.TabGroup
        BreakdownTab                matlab.ui.container.Tab
        UITable                     matlab.ui.control.Table
        UIAxes_histSelect           matlab.ui.control.UIAxes
        UIAxes_imSelect             matlab.ui.control.UIAxes
        HistogramsTab               matlab.ui.container.Tab
        UIAxes_imAVG                matlab.ui.control.UIAxes
        UIAxes_histAVG              matlab.ui.control.UIAxes
        UIAxes                      matlab.ui.control.UIAxes
        TotalMaximumEditFieldLabel  matlab.ui.control.Label
        TotalMaximumEditField       matlab.ui.control.NumericEditField
        TotalMinimumEditFieldLabel  matlab.ui.control.Label
        TotalMinimumEditField       matlab.ui.control.NumericEditField
        TotalMeanEditFieldLabel     matlab.ui.control.Label
        TotalMeanEditField          matlab.ui.control.NumericEditField
        IntensityEditFieldLabel     matlab.ui.control.Label
        IntensityEditField          matlab.ui.control.NumericEditField
        PercentileSpinnerLabel      matlab.ui.control.Label
        PercentileSpinner           matlab.ui.control.Spinner
        ColorChannelEditFieldLabel  matlab.ui.control.Label
        ColorChannelEditField       matlab.ui.control.EditField
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        total_image
        
        UIAxes_prct
        
        Table1
        
    end
    
    methods (Access = private)
        
        
        function displayEndPointsScatterplot(app)
            MeanIntensity = app.Table1.('Mean Intensity');
            x = ones(1, length(MeanIntensity));
            plot(x, MeanIntensity, 'bx', 'Parent',app.UIAxes);
            hold(app.UIAxes, "on");
            
            Thresh = app.Table1.('Threshold');
            x = 1.15* ones(1, length(Thresh));
            plot(x, Thresh, 'rx', 'Parent',app.UIAxes);
            
            grid(app.UIAxes, "on");
            app.UIAxes.XLim = [0.9, 1.3];
            app.UIAxes.XTick = [1, 1.15];
            app.UIAxes.XTickLabels = {'Mean Intensity','Threshold'};
            hold(app.UIAxes, "off");
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            FileList = dir('*.TIF');
            app.FileListListBox.Items = {FileList.name};
            app.CurrentFolderEditField.Value = cd;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {739, 739};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {376, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Button pushed function: ChooseFolderButton
        function ChooseFolderButtonPushed(app, event)
            try
                selpath = uigetdir;
                cd(selpath)
                app.CurrentFolderEditField.Value = selpath;
                FileList = dir('*.TIF');
                app.FileListListBox.Items = {FileList.name};
                app.FileListListBox.Value = {};
            catch ME
                % If problem reading image, display error message
                uialert(app.UIFigure, ME.message, 'Something Went Wrong');
                return;
            end 
        end

        % Value changed function: FileListListBox
        function FileListListBoxValueChanged(app, event)
            selected_file = app.FileListListBox.Value{1};
            selected_img = imread(selected_file);
            imshow(selected_img,[],'Parent',app.UIAxes_imSelect);
            histogram(selected_img,'BinMethod','integers','Normalization', 'probability','Parent',app.UIAxes_histSelect, 'EdgeColor', 'none', 'FaceColor', 'blue');
            
            selected_channel = selected_file(end-5:end);
            app.ColorChannelEditField.Value = selected_channel(1:2);
            prog = uiprogressdlg(app.UIFigure,'Title','Please Wait',...
                'Message','Averaging images');
            
            app.total_image = zeros(size(imread(selected_file)));
            num_img = 0;
            
            sz = [0 3];
            varTypes = {'string','double','double'};
            varNames = {'File Name','Mean Intensity','Threshold'};
            app.Table1 = table('Size',sz,'VariableTypes',varTypes,'VariableNames',varNames);
            
            %figure;
            
            for ii = 1:size(app.FileListListBox.Items, 2)
                current_file = app.FileListListBox.Items{ii};
                current_channel = current_file(end-5:end);
                
                if (current_channel == selected_channel)
                    current_img = imread(current_file);
                    
                    if(size(current_img) == size(app.total_image))
                        
                        app.Table1(end+1,:) = {current_file,mean(current_img(:)), prctile(current_img(:), app.PercentileSpinner.Value)};
                        app.total_image = app.total_image + double(current_img);
                        num_img = num_img + 1;
                        
                        if ~ismember(current_file, app.FileListListBox.Value)
                            app.FileListListBox.Value{end+1} = current_file;
                        else
                            removeStyle(app.UITable);
                            s1 = uistyle('BackgroundColor','y', 'FontColor', 'b', 'FontWeight', 'bold');
                            addStyle(app.UITable,s1,'row',[num_img]);
                            app.UIAxes_imSelect.Title.BackgroundColor = 'y';
                            app.UIAxes_imSelect.Title.Color = 'b';
                        end
                        
                        %imshow(app.total_image,[]);
                    end
                end
                prog.Value = ii/size(app.FileListListBox.Items, 2);
            end
            
            prog.Message = 'Displaying figures';
            
            app.total_image = app.total_image./num_img;
            imshow(app.total_image,[],'Parent',app.UIAxes_imAVG);
            histogram(app.total_image,'BinMethod','integers','Normalization', 'probability','Parent',app.UIAxes_histAVG, 'EdgeColor', 'none', 'FaceColor', 'blue');
            
            app.TotalMaximumEditField.Value = max(app.total_image(:));
            app.TotalMinimumEditField.Value = min(app.total_image(:));
            app.TotalMeanEditField.Value = mean(app.total_image(:));
            app.UITable.Data = app.Table1;
            
            PercentileSpinnerValueChanged(app, event)
            displayEndPointsScatterplot(app);
            
            pause(1);
            close(prog);                       
        end

        % Button pushed function: HelpButton
        function HelpButtonPushed(app, event)
            message = sprintf('Please choose folder containing d0, d1, d2, d3,...');
            uialert(app.UIFigure, message,'Help',...
            'Icon','info');
        end

        % Value changed function: PercentileSpinner
        function PercentileSpinnerValueChanged(app, event)
            app.IntensityEditField.Value = prctile(app.total_image(:), app.PercentileSpinner.Value);
            
            delete(app.UIAxes_prct);
            app.UIAxes_prct = xline(app.UIAxes_histAVG, app.IntensityEditField.Value, '--r');
        end

        % Value changed function: IntensityEditField
        function IntensityEditFieldValueChanged(app, event)
            delete(app.UIAxes_prct);
            app.UIAxes_prct = xline(app.UIAxes_histAVG, app.IntensityEditField.Value, '--r');
            
            num_less = sum(app.total_image(:) < app.IntensityEditField.Value);
            num_equal = sum(app.total_image(:) == app.IntensityEditField.Value);
            app.PercentileSpinner.Value = (num_less + 0.5*num_equal)*100 / length(app.total_image(:));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.CurrentFolderEditField = uieditfield(app.LeftPanel, 'text');
            app.CurrentFolderEditField.Value = 'C:\';
            app.ChooseFolderButton = uibutton(app.LeftPanel, 'push');
            app.ChooseFolderButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseFolderButtonPushed, true);
            app.ChooseFolderButton.Text = 'Choose Folder';
            app.FileListListBoxLabel = uilabel(app.LeftPanel);
            app.FileListListBoxLabel.Text = 'File List:';
            app.FileListListBox = uilistbox(app.LeftPanel);
            app.FileListListBox.Multiselect = 'on';
            app.FileListListBox.ValueChangedFcn = createCallbackFcn(app, @FileListListBoxValueChanged, true);
            app.FileListListBox.Value = {};
            app.HelpButton = uibutton(app.LeftPanel, 'push');
            app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.HelpButton.Text = 'Help';
            app.RightPanel = uipanel(app.GridLayout);
            app.TabGroup = uitabgroup(app.RightPanel);
            app.BreakdownTab = uitab(app.TabGroup);
            app.BreakdownTab.Title = 'Breakdown';
            app.UITable = uitable(app.BreakdownTab);
            app.UITable.ColumnName = {'File Name'; 'Mean Intensity'; 'Threshold'};
            app.UITable.RowName = {};
            app.UITable.ColumnSortable = [false true true];
            app.UIAxes_histSelect = uiaxes(app.BreakdownTab);
            title(app.UIAxes_histSelect, 'Selected File Histogram')
            zlabel(app.UIAxes_histSelect, 'Z')
            app.UIAxes_imSelect = uiaxes(app.BreakdownTab);
            title(app.UIAxes_imSelect, 'Selected Image')
            app.UIAxes_imSelect.XTick = [];
            app.UIAxes_imSelect.YTick = [];
            app.HistogramsTab = uitab(app.TabGroup);
            app.HistogramsTab.Title = 'Histograms';
            app.UIAxes_imAVG = uiaxes(app.HistogramsTab);
            title(app.UIAxes_imAVG, 'Average Image')
            app.UIAxes_imAVG.XTick = [];
            app.UIAxes_imAVG.YTick = [];
            app.UIAxes_histAVG = uiaxes(app.HistogramsTab);
            title(app.UIAxes_histAVG, 'Average Histogram')
            zlabel(app.UIAxes_histAVG, 'Z')
            app.UIAxes = uiaxes(app.HistogramsTab);
            title(app.UIAxes, 'Scatterplot of Background Endpoints')
            ylabel(app.UIAxes, 'Intensity')
            app.TotalMaximumEditFieldLabel = uilabel(app.RightPanel);
            app.TotalMaximumEditFieldLabel.Text = 'Total Maximum';
            app.TotalMaximumEditField = uieditfield(app.RightPanel, 'numeric');
            app.TotalMaximumEditField.Editable = 'off';
            app.TotalMinimumEditFieldLabel = uilabel(app.RightPanel);
            app.TotalMinimumEditFieldLabel.Text = 'Total Minimum';
            app.TotalMinimumEditField = uieditfield(app.RightPanel, 'numeric');
            app.TotalMinimumEditField.Editable = 'off';
            app.TotalMeanEditFieldLabel = uilabel(app.RightPanel);
            app.TotalMeanEditFieldLabel.Text = 'Total Mean';
            app.TotalMeanEditField = uieditfield(app.RightPanel, 'numeric');
            app.TotalMeanEditField.Editable = 'off';
            app.IntensityEditFieldLabel = uilabel(app.RightPanel);
            app.IntensityEditFieldLabel.Text = 'Intensity';
            app.IntensityEditField = uieditfield(app.RightPanel, 'numeric');
            app.IntensityEditField.ValueChangedFcn = createCallbackFcn(app, @IntensityEditFieldValueChanged, true);
            app.PercentileSpinnerLabel = uilabel(app.RightPanel);
            app.PercentileSpinnerLabel.Text = 'Percentile';
            app.PercentileSpinner = uispinner(app.RightPanel);
            app.PercentileSpinner.Step = 0.5;
            app.PercentileSpinner.Limits = [0 100];
            app.PercentileSpinner.ValueChangedFcn = createCallbackFcn(app, @PercentileSpinnerValueChanged, true);
            app.PercentileSpinner.Value = 99.5;
            app.ColorChannelEditFieldLabel = uilabel(app.RightPanel);
            app.ColorChannelEditFieldLabel.Text = 'Color Channel';
            app.ColorChannelEditField = uieditfield(app.RightPanel, 'text');
            app.ColorChannelEditField.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AverageHistogramAnalysis_Multi

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end