classdef AverageHistogramAnalysis_OverlayHist < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout                     matlab.ui.container.GridLayout
        LeftPanel                      matlab.ui.container.Panel
        CurrentFolderEditField         matlab.ui.control.EditField
        ChooseFolderButton             matlab.ui.control.Button
        FileListListBoxLabel           matlab.ui.control.Label
        FileListListBox                matlab.ui.control.ListBox
        HelpButton                     matlab.ui.control.Button
        ButtonGroup                    matlab.ui.container.ButtonGroup
        overlayhistogramsButton        matlab.ui.control.RadioButton
        clearprevioushistogramsButton  matlab.ui.control.RadioButton
        RightPanel                     matlab.ui.container.Panel
        PercentileSpinnerLabel         matlab.ui.control.Label
        PercentileSpinner              matlab.ui.control.Spinner
        PixelValueEditFieldLabel       matlab.ui.control.Label
        PixelValueEditField            matlab.ui.control.NumericEditField
        MaximumEditFieldLabel          matlab.ui.control.Label
        MaximumEditField               matlab.ui.control.NumericEditField
        MinimumEditFieldLabel          matlab.ui.control.Label
        MinimumEditField               matlab.ui.control.NumericEditField
        MeanEditFieldLabel             matlab.ui.control.Label
        MeanEditField                  matlab.ui.control.NumericEditField
        HistogramColorDropDownLabel    matlab.ui.control.Label
        HistogramColorDropDown         matlab.ui.control.DropDown
        UIAxes_im                      matlab.ui.control.UIAxes
        UIAxes_hist                    matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = public)
        total_image
        
        UIAxes_prct
        
    end
    
    methods (Access = private)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            FileList = dir('*.TIF');
            app.FileListListBox.Items = {FileList.name};
            app.CurrentFolderEditField.Value = cd;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {742, 742};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {376, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Button pushed function: ChooseFolderButton
        function ChooseFolderButtonPushed(app, event)
            try
                selpath = uigetdir;
                cd(selpath)
                app.CurrentFolderEditField.Value = selpath;
                FileList = dir('*.TIF');
                app.FileListListBox.Items = {FileList.name};
                app.FileListListBox.Value = {};
            catch ME
                % If problem reading image, display error message
                uialert(app.UIFigure, ME.message, 'Something Went Wrong');
                return;
            end 
        end

        % Value changed function: FileListListBox
        function FileListListBoxValueChanged(app, event)
            selected_file = app.FileListListBox.Value{1};
            selected_channel = selected_file(end-5:end);
            prog = uiprogressdlg(app.UIFigure,'Title','Please Wait',...
                'Message','Averaging images');
            
            app.total_image = zeros(size(imread(selected_file)));
            num_img = 0;
            
            %figure;
            
            for ii = 1:size(app.FileListListBox.Items, 2)
                current_file = app.FileListListBox.Items{ii};
                current_channel = current_file(end-5:end);
                
                if (current_channel == selected_channel)
                    img = imread(current_file);
                    
                    if(size(img) == size(app.total_image))
                        if ~ismember(current_file, app.FileListListBox.Value)
                            app.FileListListBox.Value{end+1} = current_file;
                        end
                        
                        app.total_image = app.total_image + double(img);
                        num_img = num_img + 1;
                        
                        %imshow(app.total_image,[]);
                    end
                end
                prog.Value = ii/size(app.FileListListBox.Items, 2);
            end
            
            prog.Message = 'Displaying figures';
            
            app.total_image = app.total_image./num_img;
            imshow(app.total_image,[],'Parent',app.UIAxes_im);
            if(app.overlayhistogramsButton.Value == true) hold(app.UIAxes_hist, "on"); end
            histogram(app.total_image,'BinMethod','integers','Normalization', 'probability','Parent',app.UIAxes_hist, 'EdgeColor', 'none', 'FaceColor', app.HistogramColorDropDown.Value, 'FaceAlpha', 0.5);
            if(app.overlayhistogramsButton.Value == true) hold(app.UIAxes_hist, "off"); end
            
            app.MaximumEditField.Value = max(app.total_image(:));
            app.MinimumEditField.Value = min(app.total_image(:));
            app.MeanEditField.Value = mean(app.total_image(:));
            
            PercentileSpinnerValueChanged(app, event)
            
            pause(1);
            close(prog);                       
        end

        % Button pushed function: HelpButton
        function HelpButtonPushed(app, event)
            message = sprintf('Please choose folder containing d0, d1, d2, d3,...');
            uialert(app.UIFigure, message,'Help',...
            'Icon','info');
        end

        % Value changed function: PercentileSpinner
        function PercentileSpinnerValueChanged(app, event)
            app.PixelValueEditField.Value = prctile(app.total_image(:), app.PercentileSpinner.Value);
            
            delete(app.UIAxes_prct);
            app.UIAxes_prct = xline(app.UIAxes_hist, app.PixelValueEditField.Value, '--r');
        end

        % Value changed function: PixelValueEditField
        function PixelValueEditFieldValueChanged(app, event)
            delete(app.UIAxes_prct);
            app.UIAxes_prct = xline(app.UIAxes_hist, app.PixelValueEditField.Value, '--r');
            
            num_less = sum(app.total_image(:) < app.PixelValueEditField.Value);
            num_equal = sum(app.total_image(:) == app.PixelValueEditField.Value);
            app.PercentileSpinner.Value = (num_less + 0.5*num_equal)*100 / length(app.total_image(:));
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.CurrentFolderEditField = uieditfield(app.LeftPanel, 'text');
            app.CurrentFolderEditField.Value = 'C:\';
            app.ChooseFolderButton = uibutton(app.LeftPanel, 'push');
            app.ChooseFolderButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseFolderButtonPushed, true);
            app.ChooseFolderButton.Text = 'Choose Folder';
            app.FileListListBoxLabel = uilabel(app.LeftPanel);
            app.FileListListBoxLabel.Text = 'File List:';
            app.FileListListBox = uilistbox(app.LeftPanel);
            app.FileListListBox.Multiselect = 'on';
            app.FileListListBox.ValueChangedFcn = createCallbackFcn(app, @FileListListBoxValueChanged, true);
            app.FileListListBox.Value = {};
            app.HelpButton = uibutton(app.LeftPanel, 'push');
            app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.HelpButton.Text = 'Help';
            app.ButtonGroup = uibuttongroup(app.LeftPanel);
            app.overlayhistogramsButton = uiradiobutton(app.ButtonGroup);
            app.overlayhistogramsButton.Text = 'overlay histograms';
            app.overlayhistogramsButton.Value = true;
            app.clearprevioushistogramsButton = uiradiobutton(app.ButtonGroup);
            app.clearprevioushistogramsButton.Text = 'clear previous histograms';
            app.RightPanel = uipanel(app.GridLayout);
            app.PercentileSpinnerLabel = uilabel(app.RightPanel);
            app.PercentileSpinnerLabel.Text = 'Percentile';
            app.PercentileSpinner = uispinner(app.RightPanel);
            app.PercentileSpinner.Limits = [0 100];
            app.PercentileSpinner.ValueChangedFcn = createCallbackFcn(app, @PercentileSpinnerValueChanged, true);
            app.PercentileSpinner.Value = 90;
            app.PixelValueEditFieldLabel = uilabel(app.RightPanel);
            app.PixelValueEditFieldLabel.Text = 'Pixel Value';
            app.PixelValueEditField = uieditfield(app.RightPanel, 'numeric');
            app.PixelValueEditField.ValueChangedFcn = createCallbackFcn(app, @PixelValueEditFieldValueChanged, true);
            app.MaximumEditFieldLabel = uilabel(app.RightPanel);
            app.MaximumEditFieldLabel.Text = 'Maximum';
            app.MaximumEditField = uieditfield(app.RightPanel, 'numeric');
            app.MaximumEditField.Editable = 'off';
            app.MinimumEditFieldLabel = uilabel(app.RightPanel);
            app.MinimumEditFieldLabel.Text = 'Minimum';
            app.MinimumEditField = uieditfield(app.RightPanel, 'numeric');
            app.MinimumEditField.Editable = 'off';
            app.MeanEditFieldLabel = uilabel(app.RightPanel);
            app.MeanEditFieldLabel.Text = 'Mean';
            app.MeanEditField = uieditfield(app.RightPanel, 'numeric');
            app.MeanEditField.Editable = 'off';
            app.HistogramColorDropDownLabel = uilabel(app.RightPanel);
            app.HistogramColorDropDownLabel.Text = 'Histogram Color';
            app.HistogramColorDropDown = uidropdown(app.RightPanel);
            app.HistogramColorDropDown.Items = {'blue', 'red', 'green', 'cyan', 'magenta', 'yellow', 'black'};
            app.HistogramColorDropDown.Value = 'blue';
            app.UIAxes_im = uiaxes(app.RightPanel);
            title(app.UIAxes_im, 'Average Image')
            app.UIAxes_im.XTick = [];
            app.UIAxes_im.YTick = [];
            app.UIAxes_hist = uiaxes(app.RightPanel);
            title(app.UIAxes_hist, 'Average Histogram')
            zlabel(app.UIAxes_hist, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = AverageHistogramAnalysis_OverlayHist

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end